classdef ExtendedBundledDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Extended_BundledPanel          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        SINADBundledEditFieldLabel     matlab.ui.control.Label
        SINADBundledEditField          matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        WindowListBoxLabel             matlab.ui.control.Label
        WindowListBox                  matlab.ui.control.ListBox
        SignalAmplitudeEditFieldLabel  matlab.ui.control.Label
        SignalAmplitudeEditField       matlab.ui.control.NumericEditField
        SignalFrequencyEditFieldLabel  matlab.ui.control.Label
        SignalFrequencyEditField       matlab.ui.control.NumericEditField
        NoiseAmplitudeEditFieldLabel   matlab.ui.control.Label
        NoiseAmplitudeEditField        matlab.ui.control.NumericEditField
        NoiseFrequencyEditFieldLabel   matlab.ui.control.Label
        NoiseFrequencyEditField        matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel     matlab.ui.control.Label
        SamplingRateEditField          matlab.ui.control.NumericEditField
        InputFrequencyNumberEditFieldLabel  matlab.ui.control.Label
        InputFrequencyNumberEditField  matlab.ui.control.NumericEditField
        CalculateSINADBundledButton    matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        If1                            matlab.ui.control.NumericEditField
        If2                            matlab.ui.control.NumericEditField
        If3                            matlab.ui.control.NumericEditField
        If4                            matlab.ui.control.NumericEditField
        If5                            matlab.ui.control.NumericEditField
        If6                            matlab.ui.control.NumericEditField
        If7                            matlab.ui.control.NumericEditField
        If8                            matlab.ui.control.NumericEditField
        If9                            matlab.ui.control.NumericEditField
        If10                           matlab.ui.control.NumericEditField
        If11                           matlab.ui.control.NumericEditField
        If12                           matlab.ui.control.NumericEditField
        If13                           matlab.ui.control.NumericEditField
        If14                           matlab.ui.control.NumericEditField
        If15                           matlab.ui.control.NumericEditField
        If16                           matlab.ui.control.NumericEditField
        If17                           matlab.ui.control.NumericEditField
        If18                           matlab.ui.control.NumericEditField
        If19                           matlab.ui.control.NumericEditField
        If20                           matlab.ui.control.NumericEditField
        If21                           matlab.ui.control.NumericEditField
        If22                           matlab.ui.control.NumericEditField
        If23                           matlab.ui.control.NumericEditField
        If24                           matlab.ui.control.NumericEditField
        If25                           matlab.ui.control.NumericEditField
        If26                           matlab.ui.control.NumericEditField
        If27                           matlab.ui.control.NumericEditField
        If28                           matlab.ui.control.NumericEditField
        If29                           matlab.ui.control.NumericEditField
        If30                           matlab.ui.control.NumericEditField
        If31                           matlab.ui.control.NumericEditField
        If32                           matlab.ui.control.NumericEditField
        Button                         matlab.ui.control.Button
        CompareSINADButton             matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        SaveAllDataButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        SignalAmplitude
        SignalFrequency
        WindowingOption
        NoiseFrequency
        NoiseAmpltiude
        MainDialog
        PreviousInputFrequency
        SignalData
        FreqLines
        AllSINAD
        Input_freq_All
        
        MainsinadBundledratio;
        Mainfreq_lines;
        Mainfftsignal1;
        Mainfftsignal2;
        Mainfftsignal3;
        Mainfftsignal4;
        Mainplotvar;
        
        % Description
        
        
    end
    
    methods (Access = private)
        
                function [Sinad_ratio,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,noisebarCollec] = Extended(app,signalData,samplingrate,input_freq)
            
    windowedSignal = [];
    signalWindowed1 = signalData(:).*kaiser(length(signalData));
    signalWindowed2 = signalData(:).*hamming(length(signalData));
    signalWindowed3 = signalData(:).*gausswin(length(signalData));
    signalWindowed4 = signalData(:).*hann(length(signalData));
    windowedSignal = [windowedSignal;signalWindowed1;signalWindowed2;signalWindowed3;signalWindowed4];
    % checking Sampling Rate
    if samplingrate < 0
        error('Sampling Rate is Negative')
    end
    % remove DC offset
    winSigOff = [];
    signalWindoff1 = signalWindowed1 - mean(signalWindowed1);
    signalWindoff2 = signalWindowed2 - mean(signalWindowed2);
    signalWindoff3 = signalWindowed3 - mean(signalWindowed3);
    signalWindoff4 = signalWindowed4 - mean(signalWindowed4);
    winSigOff = [windowedSignal;signalWindoff1;signalWindoff2;signalWindoff3;signalWindoff4];
    
    % Number of Data Points
    Number_of_Data_Points = length(signalData);

    % FFT transformation [only real values taken into consideration]
    fft_collec = [];
    fft_signal1 = abs(fft(signalWindoff1));
    fft_signal2 = abs(fft(signalWindoff2));
    fft_signal3 = abs(fft(signalWindoff3));
    fft_signal4 = abs(fft(signalWindoff4));
    fft_collec = [fft_collec;fft_signal1;fft_signal2;fft_signal3;fft_signal4];

    
    % Removing bin 1 [DC bin]
    down_index = floor(Number_of_Data_Points/2);
    fft_signal1 = fft_signal1(2:down_index);
    fft_signal2 = fft_signal2(2:down_index);
    fft_signal3 = fft_signal3(2:down_index);
    fft_signal4 = fft_signal4(2:down_index);
    
    fftsignal = [];
    fftsignal1 = mag2db(fft_signal1/length(fft_signal1));
    fftsignal2 = mag2db(fft_signal2/length(fft_signal2));
    fftsignal3 = mag2db(fft_signal3/length(fft_signal3));
    fftsignal4 = mag2db(fft_signal4/length(fft_signal4));
    fftsignal = [fftsignal;fftsignal1;fftsignal2;fftsignal3;fftsignal4];
    % Number of Frequency Lines
    no_frequency_lines = samplingrate*(1:(Number_of_Data_Points/2)-1)/Number_of_Data_Points;
    freq_lines = no_frequency_lines
    
    app.FreqLines = freq_lines;

    %     %Checking Input Fundamental Frequency and Calculated Fundamental
    %     %Frequency
    %     %Spectral Leakage Reduction around INPUT Fundamental Frequencies
      fft_signal1(input_freq-1) = 0;
      fft_signal1(input_freq+1) = 0;
      fft_signal2(input_freq-1) = 0;
      fft_signal2(input_freq+1) = 0;
      fft_signal3(input_freq-1) = 0;
      fft_signal3(input_freq+1) = 0;
      fft_signal4(input_freq-1) = 0;
      fft_signal4(input_freq+1) = 0;
    
    noisebarCollec = [];
    plotvar1 = (sum(fftsignal1) - fftsignal1(input_freq))/(length(fftsignal1)-1)
    plotvar2 = (sum(fftsignal2) - fftsignal2(input_freq))/(length(fftsignal2)-1)
    plotvar3 = (sum(fftsignal3) - fftsignal3(input_freq))/(length(fftsignal3)-1)
    plotvar4 = (sum(fftsignal4) - fftsignal4(input_freq))/(length(fftsignal4)-1)
    noisebarCollec = [noisebarCollec;plotvar1;plotvar2;plotvar3;plotvar4];


    %Calculating Numerator and Denominator for SINAD ratio
    numerator1 = fft_signal1(input_freq).^2;
    denominator1 = sum(fft_signal1.^2)-(numerator1);
    numerator2 = fft_signal2(input_freq).^2;
    denominator2 = sum(fft_signal2.^2)-(numerator2);
    numerator3 = fft_signal3(input_freq).^2;
    denominator3 = sum(fft_signal3.^2)-(numerator3);
    numerator4 = fft_signal4(input_freq).^2;
    denominator4 = sum(fft_signal4.^2)-(numerator4);
  
    fft_signal1 = fft_collec(1);
    fft_signal2 = fft_collec(2);
    fft_signal3 = fft_collec(3);
    fft_signal4 = fft_collec(4);
    
    %SINAD CALCULATION
    Sinad_ratio = [];
    Sinad_ratio1 = (10*log(numerator1/denominator1))/2;
    Sinad_ratio2 = (10*log(numerator2/denominator2))/2;
    Sinad_ratio3 = (10*log(numerator3/denominator3))/2;
    Sinad_ratio4 = (10*log(numerator4/denominator4))/2;
    Sinad_ratio5 = sinad(signalData);
    
    Sinad_ratio = [Sinad_ratio;Sinad_ratio1;Sinad_ratio2;Sinad_ratio3;Sinad_ratio4;Sinad_ratio5];
        end


        function [sinadBundled,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,noisebarCollec] = ExtendedBundled(app,signalData,samplingrate,input_freq)
            sinadBundled = [0;0;0;0;0];        
            app.Input_freq_All = input_freq;
            for freqindex = 1:length(input_freq)
                [sinadcollec,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,noisebarCollec] = Extended(app,signalData,samplingrate,input_freq(freqindex))
                for sinadtotal = 1:5
                    sinadBundled(sinadtotal) = sinadBundled(sinadtotal)+sinadcollec(sinadtotal);
                end
                
            end 
            app.AllSINAD = sinadcollec(1:4);
        end
            
        
        function [frequencies,fundamental_frequency,fundamental_amplitude] = FrequencyExtractor(~,fft_signal,No_of_Freq_Lines)

            %fundamental Frequency Amplitude and Index
                [max_a, max_a] = max((fft_signal));
                fundamental_amplitude = fft_signal(max_a);

            %Higher Frequencies and fundamental frequency
                frequencies = [];
                fundamental_frequency = [];
                for i = (1:(No_of_Freq_Lines-1))
                    if fundamental_amplitude == fft_signal(i)
                        fundamental_frequency = [fundamental_frequency i];
                        frequencies = [frequencies i];
                    elseif 0.2 <= fft_signal(i)
                        frequencies = [frequencies i];
                    end
                end

        end
 
        %calculating ENOB from SINAD
        function [ENOB] = calculateENOB(SINAD)
            ENOB = (SINAD - 1.76)/6.02
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainDialog = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainDialog.ExtendedBundledButton.Enable = 'on';
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            input_freq_number = app.InputFrequencyNumberEditField.Value;
            InputFreqCollec = [app.If1;app.If2;app.If3;app.If4;app.If5;app.If6;app.If7;app.If8;app.If9;app.If10;app.If11;app.If12;app.If13;app.If14;app.If15;app.If16;app.If17;app.If18;app.If19;app.If20;app.If21;app.If22;app.If23;app.If24;app.If25;app.If26;app.If27;app.If28;app.If29;app.If30;app.If31;app.If32]
            if(input_freq_number > 2)
                for index = 2:input_freq_number
                    InputFreqCollec(index).Visible = 'on';
                    
                end
                
            end
            if (input_freq_number < 32)
                for indexoff = input_freq_number+1 : 32
                    if(strcmpi(InputFreqCollec(indexoff).Visible,'on'))
                        InputFreqCollec(indexoff).Visible = 'off'
                        InputFreqCollec(indexoff).Value = 0
                    end
                end
            end
           
        end

        % Button pushed function: CalculateSINADBundledButton
        function CalculateSINADBundledButtonPushed(app, event)
              Am = app.SignalAmplitudeEditField.Value;
            Af = app.SignalFrequencyEditField.Value;
            Nam = app.NoiseAmplitudeEditField.Value;
            Naf = app.NoiseFrequencyEditField.Value;
            Sam = app.SamplingRateEditField.Value;
            windowing = app.WindowListBox.Value;
            t = 0:1/Sam:1-1/Sam;
            signal = Am*sin(2*pi*Af*t)+Nam*sin(2*pi*Naf*t)+Nam*randn(size(t));
            app.SignalData = signal;
            input_freq_number = app.InputFrequencyNumberEditField.Value;
            InputFreqCollec = [app.If1;app.If2;app.If3;app.If4;app.If5;app.If6;app.If7;app.If8;app.If9;app.If10;app.If11;app.If12;app.If13;app.If14;app.If15;app.If16;app.If17;app.If18;app.If19;app.If20;app.If21;app.If22;app.If23;app.If24;app.If25;app.If26;app.If27;app.If28;app.If29;app.If30;app.If31;app.If32]
            inputfreq = [];
            
            for index = 1:input_freq_number
                inputfreq = [inputfreq;InputFreqCollec(index).Value];
            end
            
            [sinadBundled,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,noisebarCollec] = ExtendedBundled(app,signal,Sam,inputfreq)
            windowing = app.WindowListBox.Value;
            
            app.MainsinadBundledratio = sinadBundled;
            app.Mainfftsignal1 = fftsignal1;
            app.Mainfftsignal2 = fftsignal2;
            app.Mainfftsignal3 = fftsignal3;
            app.Mainfftsignal4 = fftsignal4;
            app.Mainfreq_lines = freq_lines;
            app.Mainplotvar = noisebarCollec;
            
            if windowing == "Kaiser"
                app.SINADBundledEditField.Value = sinadBundled(1);
                plot(app.UIAxes, freq_lines, fftsignal1,freq_lines,noisebarCollec(1)*ones(length(freq_lines),1),freq_lines,max(fftsignal1)*ones(length(freq_lines)),'--k');               
            
            elseif windowing == "Hamming"    
                app.SINADBundledEditField.Value = sinadBundled(2);
                 plot(app.UIAxes, freq_lines, fftsignal2,freq_lines,noisebarCollec(2)*ones(length(freq_lines),1),freq_lines,max(fftsignal2)*ones(length(freq_lines)),'--k');               
            elseif windowing == "Gaussian"    
                app.SINADBundledEditField.Value = sinadBundled(3);
               plot(app.UIAxes, freq_lines, fftsignal3,freq_lines,noisebarCollec(3)*ones(length(freq_lines),1),freq_lines,max(fftsignal3)*ones(length(freq_lines)),'--k');               
           
            elseif windowing == "Hann"     
                app.SINADBundledEditField.Value = sinadBundled(4);
                plot(app.UIAxes, freq_lines, fftsignal4,freq_lines,noisebarCollec(4)*ones(length(freq_lines),1),freq_lines,max(fftsignal4)*ones(length(freq_lines)),'--k');               
            end  
        end

        % Button pushed function: CompareSINADButton
        function CompareSINADButtonPushed(app, event)
               app.UIAxes2.Visible = 'on';
               new_SINADBundled = app.MainsinadBundledratio(1:4)
               bar(app.UIAxes2,new_SINADBundled)
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
             windowing = app.WindowListBox.Value;
             if windowing == "Kaiser"
                app.SINADBundledEditField.Value = app.MainsinadBundledratio(1);
                plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal1,app.Mainfreq_lines,app.Mainplotvar(1)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal1)*ones(length(app.Mainfreq_lines)),'--k');               
            
            elseif windowing == "Hamming"    
                app.SINADBundledEditField.Value = app.MainsinadBundledratio(2);
                 plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal2,app.Mainfreq_lines,app.Mainplotvar(2)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal2)*ones(length(app.Mainfreq_lines)),'--k');               
            elseif windowing == "Gaussian"    
                app.SINADBundledEditField.Value = app.MainsinadBundledratio(3);
               plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal3,app.Mainfreq_lines,app.Mainplotvar(3)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal3)*ones(length(app.Mainfreq_lines)),'--k');               
           
            elseif windowing == "Hann"     
                app.SINADBundledEditField.Value = app.MainsinadBundledratio(4);
                plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal4,app.Mainfreq_lines,app.Mainplotvar(4)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal4)*ones(length(app.Mainfreq_lines)),'--k');               
            end  
        end

        % Button pushed function: SaveAllDataButton
        function SaveAllDataButtonPushed(app, event)
            fid = fopen('Extended_Bundled_SignalData.txt','a+');
            fprintf(fid,'-----------------------------------\n')
            fprintf(fid,'%f \n',[app.SignalData]);
            fclose(fid);
%             
            fid2 = fopen('Extended_Bundled_SinadData.txt','a+');
            fprintf(fid2,'----1.Kaiser----2.Hamming----3.Gaussian-----4.Hann------------------\n')
            fprintf(fid2,'%f \n',[app.AllSINAD]);
            fclose(fid);
            
            fid3 = fopen('Extended_Bundled_Input_freq.txt','a+');
            fprintf(fid3,'-----------------------------------\n')
            fprintf(fid3,'%f \n',[app.Input_freq_All]);
            fclose(fid);
            
%                  fid = fopen('SignalData.txt','a+');
%             fprintf(fid,'-----------------------------------\n')
%             fprintf(fid,'%f \n',[app.SignalData]);
%             fclose(fid);
%             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Extended_BundledPanel = uipanel(app.UIFigure);
            app.Extended_BundledPanel.Title = 'Extended_Bundled';
            app.UIAxes = uiaxes(app.Extended_BundledPanel);
            title(app.UIAxes, 'FFT Plot')
            xlabel(app.UIAxes, 'Freq')
            ylabel(app.UIAxes, 'SINAD')
            app.SINADBundledEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.SINADBundledEditFieldLabel.Text = 'SINAD Bundled';
            app.SINADBundledEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.UITable = uitable(app.Extended_BundledPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.WindowListBoxLabel = uilabel(app.Extended_BundledPanel);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.Extended_BundledPanel);
            app.WindowListBox.Items = {'Kaiser', 'Hamming', 'Gaussian', 'Hann'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Kaiser';
            app.SignalAmplitudeEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.SignalAmplitudeEditFieldLabel.Text = 'Signal Amplitude';
            app.SignalAmplitudeEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.SignalAmplitudeEditField.Limits = [0 100];
            app.SignalAmplitudeEditField.Value = 0.5;
            app.SignalFrequencyEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.SignalFrequencyEditFieldLabel.Text = 'Signal Frequency';
            app.SignalFrequencyEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.SignalFrequencyEditField.Limits = [0 Inf];
            app.SignalFrequencyEditField.Value = 500;
            app.NoiseAmplitudeEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.NoiseAmplitudeEditFieldLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.NoiseAmplitudeEditField.Limits = [0 100];
            app.NoiseAmplitudeEditField.Value = 0.2;
            app.NoiseFrequencyEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.NoiseFrequencyEditFieldLabel.Text = 'Noise Frequency';
            app.NoiseFrequencyEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.NoiseFrequencyEditField.Limits = [0 Inf];
            app.NoiseFrequencyEditField.Value = 200;
            app.SamplingRateEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.SamplingRateEditField.Limits = [0 Inf];
            app.SamplingRateEditField.Value = 1200;
            app.InputFrequencyNumberEditFieldLabel = uilabel(app.Extended_BundledPanel);
            app.InputFrequencyNumberEditFieldLabel.Text = 'Input Frequency Number';
            app.InputFrequencyNumberEditField = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.InputFrequencyNumberEditField.Limits = [0 32];
            app.InputFrequencyNumberEditField.Value = 2;
            app.CalculateSINADBundledButton = uibutton(app.Extended_BundledPanel, 'push');
            app.CalculateSINADBundledButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSINADBundledButtonPushed, true);
            app.CalculateSINADBundledButton.Text = 'Calculate SINAD Bundled';
            app.UITable2 = uitable(app.Extended_BundledPanel);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.If1 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If1.Value = 500;
            app.If2 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If2.Value = 498;
            app.If3 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If3.Visible = 'off';
            app.If4 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If4.Visible = 'off';
            app.If5 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If5.Visible = 'off';
            app.If6 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If6.Visible = 'off';
            app.If7 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If7.Visible = 'off';
            app.If8 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If8.Visible = 'off';
            app.If9 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If9.Visible = 'off';
            app.If10 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If10.Visible = 'off';
            app.If11 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If11.Visible = 'off';
            app.If12 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If12.Visible = 'off';
            app.If13 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If13.Visible = 'off';
            app.If14 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If14.Visible = 'off';
            app.If15 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If15.Visible = 'off';
            app.If16 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If16.Visible = 'off';
            app.If17 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If17.Visible = 'off';
            app.If18 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If18.Visible = 'off';
            app.If19 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If19.Visible = 'off';
            app.If20 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If20.Visible = 'off';
            app.If21 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If21.Visible = 'off';
            app.If22 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If22.Visible = 'off';
            app.If23 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If23.Visible = 'off';
            app.If24 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If24.Visible = 'off';
            app.If25 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If25.Visible = 'off';
            app.If26 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If26.Visible = 'off';
            app.If27 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If27.Visible = 'off';
            app.If28 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If28.Visible = 'off';
            app.If29 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If29.Visible = 'off';
            app.If30 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If30.Visible = 'off';
            app.If31 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If31.Visible = 'off';
            app.If32 = uieditfield(app.Extended_BundledPanel, 'numeric');
            app.If32.Visible = 'off';
            app.Button = uibutton(app.Extended_BundledPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.CompareSINADButton = uibutton(app.Extended_BundledPanel, 'push');
            app.CompareSINADButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSINADButtonPushed, true);
            app.CompareSINADButton.Text = 'Compare SINAD';
            app.UIAxes2 = uiaxes(app.Extended_BundledPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'SINAD_Bundled')
            app.SaveAllDataButton = uibutton(app.Extended_BundledPanel, 'push');
            app.SaveAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllDataButtonPushed, true);
            app.SaveAllDataButton.Text = 'Save All Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtendedBundledDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end