classdef ExtendedWindowDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExtendedPanel                   matlab.ui.container.Panel
        CalculateSINADButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SINADEditFieldLabel             matlab.ui.control.Label
        SINADEditField                  matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        SignalAmplitudeEditFieldLabel   matlab.ui.control.Label
        SignalAmplitudeEditField        matlab.ui.control.NumericEditField
        SignalFrequencyEditFieldLabel   matlab.ui.control.Label
        SignalFrequencyEditField        matlab.ui.control.NumericEditField
        NoiseAmplitudeEditFieldLabel    matlab.ui.control.Label
        NoiseAmplitudeEditField         matlab.ui.control.NumericEditField
        NoiseFrequencyEditFieldLabel    matlab.ui.control.Label
        NoiseFrequencyEditField         matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        InputFrequencyEditFieldLabel    matlab.ui.control.Label
        InputFrequencyEditField         matlab.ui.control.NumericEditField
        CompareSINADofallWindowsButton  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        WindowListBoxLabel              matlab.ui.control.Label
        WindowListBox                   matlab.ui.control.ListBox
        SaveAllDataButton               matlab.ui.control.Button
    end

    
    properties (Access = private)

        SignalAmplitude;
        SignalFrequency;
        SignalData;
        WindowingOption;
        NoiseFrequency;
        NoiseAmpltiude;
        MainDialog;
        
        Mainsinadratio;
        Mainfreq_lines;
        Mainfftsignal1;
        Mainfftsignal2;
        Mainfftsignal3;
        Mainfftsignal4;
        Mainplotvar;
        InputFreq
        sinad_collec
        
        % Description
        
    end
    
    methods (Access = private)
                    
        function [Sinad_ratio,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,noisebarCollec] = Extended(app,signalData,samplingrate,input_freq)
            
    windowedSignal = [];
    signalWindowed1 = signalData(:).*kaiser(length(signalData));
    signalWindowed2 = signalData(:).*hamming(length(signalData));
    signalWindowed3 = signalData(:).*gausswin(length(signalData));
    signalWindowed4 = signalData(:).*hann(length(signalData));
    windowedSignal = [windowedSignal;signalWindowed1;signalWindowed2;signalWindowed3;signalWindowed4];
    % checking Sampling Rate
    if samplingrate < 0
        error('Sampling Rate is Negative')
    end
    % remove DC offset
    winSigOff = [];
    signalWindoff1 = signalWindowed1 - mean(signalWindowed1);
    signalWindoff2 = signalWindowed2 - mean(signalWindowed2);
    signalWindoff3 = signalWindowed3 - mean(signalWindowed3);
    signalWindoff4 = signalWindowed4 - mean(signalWindowed4);
    winSigOff = [windowedSignal;signalWindoff1;signalWindoff2;signalWindoff3;signalWindoff4];
    
    app.InputFreq = input_freq;
    
    % Number of Data Points
    Number_of_Data_Points = length(signalData);

    % FFT transformation [only real values taken into consideration]
    fft_collec = [];
    fft_signal1 = abs(fft(signalWindoff1));
    fft_signal2 = abs(fft(signalWindoff2));
    fft_signal3 = abs(fft(signalWindoff3));
    fft_signal4 = abs(fft(signalWindoff4));
    fft_collec = [fft_collec;fft_signal1;fft_signal2;fft_signal3;fft_signal4];

    
    % Removing bin 1 [DC bin]
    down_index = floor(Number_of_Data_Points/2);
    fft_signal1 = fft_signal1(2:down_index);
    fft_signal2 = fft_signal2(2:down_index);
    fft_signal3 = fft_signal3(2:down_index);
    fft_signal4 = fft_signal4(2:down_index);
    
    fftsignal = [];
    fftsignal1 = mag2db(fft_signal1/length(fft_signal1));
    fftsignal2 = mag2db(fft_signal2/length(fft_signal2));
    fftsignal3 = mag2db(fft_signal3/length(fft_signal3));
    fftsignal4 = mag2db(fft_signal4/length(fft_signal4));
    fftsignal = [fftsignal;fftsignal1;fftsignal2;fftsignal3;fftsignal4];
    % Number of Frequency Lines
    no_frequency_lines = samplingrate*(1:(Number_of_Data_Points/2)-1)/Number_of_Data_Points;
    freq_lines = no_frequency_lines


    %     %Checking Input Fundamental Frequency and Calculated Fundamental
    %     %Frequency
    %     %Spectral Leakage Reduction around INPUT Fundamental Frequencies
     fft_signal1(input_freq-1) = 0;
     fft_signal1(input_freq+1) = 0;
     fft_signal2(input_freq-1) = 0;
     fft_signal2(input_freq+1) = 0;
     fft_signal3(input_freq-1) = 0;
     fft_signal3(input_freq+1) = 0;
     fft_signal4(input_freq-1) = 0;
     fft_signal4(input_freq+1) = 0;
    
    noisebarCollec = [];
    plotvar1 = (sum(fftsignal1) - fftsignal1(input_freq))/(length(fftsignal1)-1)
    plotvar2 = (sum(fftsignal2) - fftsignal2(input_freq))/(length(fftsignal2)-1)
    plotvar3 = (sum(fftsignal3) - fftsignal3(input_freq))/(length(fftsignal3)-1)
    plotvar4 = (sum(fftsignal4) - fftsignal4(input_freq))/(length(fftsignal4)-1)
    noisebarCollec = [noisebarCollec;plotvar1;plotvar2;plotvar3;plotvar4];


    %Calculating Numerator and Denominator for SINAD ratio
    numerator1 = fft_signal1(input_freq).^2;
    denominator1 = sum(fft_signal1.^2)-(numerator1);
    numerator2 = fft_signal2(input_freq).^2;
    denominator2 = sum(fft_signal2.^2)-(numerator2);
    numerator3 = fft_signal3(input_freq).^2;
    denominator3 = sum(fft_signal3.^2)-(numerator3);
    numerator4 = fft_signal4(input_freq).^2;
    denominator4 = sum(fft_signal4.^2)-(numerator4);
  
    %SINAD CALCULATION
    Sinad_ratio = [];
    Sinad_ratio1 = (10*log(numerator1/denominator1))/2;
    Sinad_ratio2 = (10*log(numerator2/denominator2))/2;
    Sinad_ratio3 = (10*log(numerator3/denominator3))/2;
    Sinad_ratio4 = (10*log(numerator4/denominator4))/2;
    Sinad_ratio5 = sinad(signalData);
    
    Sinad_ratio = [Sinad_ratio;Sinad_ratio1;Sinad_ratio2;Sinad_ratio3;Sinad_ratio4;Sinad_ratio5];
    app.sinad_collec = Sinad_ratio;
        end
         
        function [frequencies,fundamental_frequency,fundamental_amplitude] = FrequencyExtractor(~,fft_signal,No_of_Freq_Lines)

            %fundamental Frequency Amplitude and Index
                [max_a, max_a] = max((fft_signal));
                fundamental_amplitude = fft_signal(max_a);

            %Higher Frequencies and fundamental frequency
                frequencies = [];
                fundamental_frequency = [];
                for i = (1:(No_of_Freq_Lines-1))
                    if fundamental_amplitude == fft_signal(i)
                        fundamental_frequency = [fundamental_frequency i];
                        frequencies = [frequencies i];
                    elseif 0.2 <= fft_signal(i)
                        frequencies = [frequencies i];
                    end
                end

        end
 
        %calculating ENOB from SINAD
        function [ENOB] = calculateENOB(SINAD)
            ENOB = (SINAD - 1.76)/6.02
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainDialog = mainapp;
          
            
        end

        % Button pushed function: CalculateSINADButton
        function CalculateSINADButtonPushed(app, event)
            Am = app.SignalAmplitudeEditField.Value;
            Af = app.SignalFrequencyEditField.Value;
            Nam = app.NoiseAmplitudeEditField.Value;
            Naf = app.NoiseFrequencyEditField.Value;
            InputFreq = app.InputFrequencyEditField.Value;
            Sam = app.SamplingRateEditField.Value;
           % [windowing] = app.WindowListBox.Value;
            windowing = app.WindowListBox.Value;
            t = 0:1/Sam:1-1/Sam;
            %signal = Am*sin(2*pi*Af*t)+Nam*sin(2*pi*Naf*t)+Nam*randn(size(t));
            signal = Am*sin(2*pi*Af*t)+Nam*sin(2*pi*Naf*t)+Nam*randn(size(t));
            app.SignalData = signal;
            
            [sinadratio,freq_lines,fftsignal1,fftsignal2,fftsignal3,fftsignal4,plotvar] = Extended(app,signal,Sam,InputFreq);
            
            app.Mainsinadratio = sinadratio;
            app.Mainfftsignal1 = fftsignal1;
            app.Mainfftsignal2 = fftsignal2;
            app.Mainfftsignal3 = fftsignal3;
            app.Mainfftsignal4 = fftsignal4;
            app.Mainfreq_lines = freq_lines;
            app.Mainplotvar = plotvar;
            
            if windowing == "Kaiser"
                app.SINADEditField.Value = sinadratio(1);
                plot(app.UIAxes, freq_lines, fftsignal1,freq_lines,plotvar(1)*ones(length(freq_lines),1),freq_lines,max(fftsignal1)*ones(length(freq_lines)),'--k');               
            
            elseif windowing == "Hamming"    
                app.SINADEditField.Value = sinadratio(2);
                 plot(app.UIAxes, freq_lines, fftsignal2,freq_lines,plotvar(2)*ones(length(freq_lines),1),freq_lines,max(fftsignal2)*ones(length(freq_lines)),'--k');               
            elseif windowing == "Gaussian"    
                app.SINADEditField.Value = sinadratio(3);
               plot(app.UIAxes, freq_lines, fftsignal3,freq_lines,plotvar(3)*ones(length(freq_lines),1),freq_lines,max(fftsignal3)*ones(length(freq_lines)),'--k');               
           
            elseif windowing == "Hann"     
                plot(app.UIAxes, freq_lines, fftsignal4,freq_lines,plotvar(4)*ones(length(freq_lines),1),freq_lines,max(fftsignal4)*ones(length(freq_lines)),'--k');               
            end    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainDialog.ExtendedButton.Enable = 'on';
            delete(app.UIFigure);
        end

        % Button pushed function: CompareSINADofallWindowsButton
        function CompareSINADofallWindowsButtonPushed(app, event)
            app.UIAxes2.Visible = 'on';
            bar(app.UIAxes2,app.Mainsinadratio)
        end

        % Value changed function: WindowListBox
        function WindowListBoxValueChanged(app, event)
            windowing = app.WindowListBox.Value;
             if windowing == "Kaiser"
                app.SINADEditField.Value = app.Mainsinadratio(1);
                plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal1,app.Mainfreq_lines,app.Mainplotvar(1)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal1)*ones(length(app.Mainfreq_lines)),'--k');               
            
            elseif windowing == "Hamming"    
                app.SINADEditField.Value = app.Mainsinadratio(2);
                 plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal2,app.Mainfreq_lines,app.Mainplotvar(2)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal2)*ones(length(app.Mainfreq_lines)),'--k');               
            elseif windowing == "Gaussian"    
                app.SINADEditField.Value = app.Mainsinadratio(3);
               plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal3,app.Mainfreq_lines,app.Mainplotvar(3)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal3)*ones(length(app.Mainfreq_lines)),'--k');               
           
            elseif windowing == "Hann"     
                app.SINADEditField.Value = app.Mainsinadratio(4);
                plot(app.UIAxes, app.Mainfreq_lines, app.Mainfftsignal4,app.Mainfreq_lines,app.Mainplotvar(4)*ones(length(app.Mainfreq_lines),1),app.Mainfreq_lines,max(app.Mainfftsignal4)*ones(length(app.Mainfreq_lines)),'--k');               
            end    
        end

        % Button pushed function: SaveAllDataButton
        function SaveAllDataButtonPushed(app, event)
            fid = fopen('Extended_SINAD_Signal_Data.txt','a+');
            fprintf(fid,'-----------------------------------\n')
            fprintf(fid,'%f \n',[app.SignalData]);
            fclose(fid);
%             
            fid2 = fopen('Extended_SINAD_SINAD_Data.txt','a+');
            fprintf(fid2,'----1.Kaiser----2.Hamming----3.Gaussian-----4.Hann-------5.MatLab_Inbuilt-----------\n')
            fprintf(fid2,'%f \n',[app.sinad_collec]);
            fclose(fid);
            
            fid3 = fopen('Extended_SINAD_InputFrequency_Data.txt','a+');
            fprintf(fid3,'-----------------------------------\n')
            fprintf(fid3,'%f \n',[app.InputFreq]);
            fclose(fid);
            
%                  fid = fopen('SignalData.txt','a+');
%             fprintf(fid,'-----------------------------------\n')
%             fprintf(fid,'%f \n',[app.SignalData]);
%             fclose(fid);
%             
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExtendedPanel = uipanel(app.UIFigure);
            app.ExtendedPanel.Title = 'Extended';
            app.CalculateSINADButton = uibutton(app.ExtendedPanel, 'push');
            app.CalculateSINADButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSINADButtonPushed, true);
            app.CalculateSINADButton.Text = 'Calculate SINAD';
            app.UIAxes = uiaxes(app.ExtendedPanel);
            title(app.UIAxes, 'SINAD Plot')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'dbFs')
            app.SINADEditFieldLabel = uilabel(app.ExtendedPanel);
            app.SINADEditFieldLabel.Text = 'SINAD';
            app.SINADEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.UITable = uitable(app.ExtendedPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.SignalAmplitudeEditFieldLabel = uilabel(app.ExtendedPanel);
            app.SignalAmplitudeEditFieldLabel.Text = 'Signal Amplitude';
            app.SignalAmplitudeEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.SignalAmplitudeEditField.Limits = [0 100];
            app.SignalAmplitudeEditField.Value = 0.5;
            app.SignalFrequencyEditFieldLabel = uilabel(app.ExtendedPanel);
            app.SignalFrequencyEditFieldLabel.Text = 'Signal Frequency';
            app.SignalFrequencyEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.SignalFrequencyEditField.Limits = [0 Inf];
            app.SignalFrequencyEditField.Value = 500;
            app.NoiseAmplitudeEditFieldLabel = uilabel(app.ExtendedPanel);
            app.NoiseAmplitudeEditFieldLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.NoiseAmplitudeEditField.Limits = [0 100];
            app.NoiseAmplitudeEditField.Value = 0.2;
            app.NoiseFrequencyEditFieldLabel = uilabel(app.ExtendedPanel);
            app.NoiseFrequencyEditFieldLabel.Text = 'Noise Frequency';
            app.NoiseFrequencyEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.NoiseFrequencyEditField.Limits = [0 Inf];
            app.NoiseFrequencyEditField.Value = 200;
            app.SamplingRateEditFieldLabel = uilabel(app.ExtendedPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.SamplingRateEditField.Limits = [0 Inf];
            app.SamplingRateEditField.Value = 1200;
            app.InputFrequencyEditFieldLabel = uilabel(app.ExtendedPanel);
            app.InputFrequencyEditFieldLabel.Text = 'Input Frequency';
            app.InputFrequencyEditField = uieditfield(app.ExtendedPanel, 'numeric');
            app.InputFrequencyEditField.Limits = [0 Inf];
            app.InputFrequencyEditField.Value = 500;
            app.CompareSINADofallWindowsButton = uibutton(app.ExtendedPanel, 'push');
            app.CompareSINADofallWindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSINADofallWindowsButtonPushed, true);
            app.CompareSINADofallWindowsButton.Text = 'Compare SINAD of all Windows';
            app.UIAxes2 = uiaxes(app.ExtendedPanel);
            title(app.UIAxes2, 'SINAD Comparison')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'SINAD')
            app.WindowListBoxLabel = uilabel(app.ExtendedPanel);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.ExtendedPanel);
            app.WindowListBox.Items = {'Kaiser', 'Hamming', 'Gaussian', 'Hann'};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @WindowListBoxValueChanged, true);
            app.WindowListBox.Value = 'Kaiser';
            app.SaveAllDataButton = uibutton(app.ExtendedPanel, 'push');
            app.SaveAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllDataButtonPushed, true);
            app.SaveAllDataButton.Text = 'Save All Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExtendedWindowDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end