classdef MainDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ExtendedButton         matlab.ui.control.Button
        ExtendedBundledButton  matlab.ui.control.Button
        SINADcalculatorLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ExtendedWindowDialog;
        ExtendedBundledDialog;
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExtendedButton
        function ExtendedButtonPushed(app, event)
            
            app.ExtendedButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ExtendedWindowDialog = ExtendedWindowDialog(app);
        end

        % Button pushed function: ExtendedBundledButton
        function ExtendedBundledButtonPushed(app, event)
            app.ExtendedBundledButton.Enable = 'off';
            
            app.ExtendedBundledDialog = ExtendedBundledDialog(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExtendedButton = uibutton(app.UIFigure, 'push');
            app.ExtendedButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendedButtonPushed, true);
            app.ExtendedButton.Text = 'Extended';
            app.ExtendedBundledButton = uibutton(app.UIFigure, 'push');
            app.ExtendedBundledButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendedBundledButtonPushed, true);
            app.ExtendedBundledButton.Text = 'Extended Bundled';
            app.SINADcalculatorLabel = uilabel(app.UIFigure);
            app.SINADcalculatorLabel.Text = 'SINAD calculator ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end